classdef DialogBoxShip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogBoxUIFigure        matlab.ui.Figure
        EditField                matlab.ui.control.NumericEditField
        TimeLabel                matlab.ui.control.Label
        ShipHitLabel             matlab.ui.control.Label
        DisatanceEditField       matlab.ui.control.NumericEditField
        DisatanceEditFieldLabel  matlab.ui.control.Label
        LaunchTorpedoButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        DialogApp
        x
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dis)
            app.CallingApp = mainapp;
            app.DisatanceEditField.Value=dis;

            app.x=dis;
            while app.LaunchTorpedoButton.Enable~="off"
           app.EditField.Value=5;
            pause(1)
            if app.LaunchTorpedoButton.Enable=="off"
                break
            end
            app.EditField.Value=4;
            pause(1)
            if app.LaunchTorpedoButton.Enable=="off"
                break
            end
            app.EditField.Value=3;
            pause(1)
            if app.LaunchTorpedoButton.Enable=="off"
                break
            end
            app.EditField.Value=2;
            pause(1)
            if app.LaunchTorpedoButton.Enable=="off"
                break
            end
            app.EditField.Value=1;
            pause(1)
            break
            end
            
            delete(app)

        end

        % Button pushed function: LaunchTorpedoButton
        function LaunchTorpedoButtonPushed(app, event)
             updateplot1(app.CallingApp,app.x);
%              update_p(app.CallingApp,1)
app.LaunchTorpedoButton.Enable='off';
%             delete(app)
        end

        % Close request function: DialogBoxUIFigure
        function DialogBoxUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogBoxUIFigure = uifigure('Visible', 'off');
            app.DialogBoxUIFigure.Name = 'Dialog Box';
            app.DialogBoxUIFigure.Icon = 'img.jpeg';
            app.DialogBoxUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogBoxUIFigureCloseRequest, true);
            app.LaunchTorpedoButton = uibutton(app.DialogBoxUIFigure, 'push');
            app.LaunchTorpedoButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchTorpedoButtonPushed, true);
            app.LaunchTorpedoButton.Text = 'Launch Torpedo';
            app.DisatanceEditFieldLabel = uilabel(app.DialogBoxUIFigure);
            app.DisatanceEditFieldLabel.Text = 'Disatance';
            app.DisatanceEditField = uieditfield(app.DialogBoxUIFigure, 'numeric');
            app.DisatanceEditField.Editable = 'off';
            app.ShipHitLabel = uilabel(app.DialogBoxUIFigure);
            app.ShipHitLabel.Text = 'Ship Hit';
            app.TimeLabel = uilabel(app.DialogBoxUIFigure);
            app.TimeLabel.Text = 'Time';
            app.EditField = uieditfield(app.DialogBoxUIFigure, 'numeric');
            app.EditField.Value = 5;
            % Show the figure after all components are created
            app.DialogBoxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBoxShip(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogBoxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogBoxUIFigure)
        end
    end
end