classdef graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphUIFigure  matlab.ui.Figure
        DoneButton     matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

     properties (Access = private)
         CallingApp   % Main app object 
         DialogApp
         troops_lost_by_ship
        troops_lost_by_sub1
        troops_lost_by_sub2
        m
        n
        o
    end
    
    properties (Access = public)
        ship_kill=0 % Description
        sub_kill=0
        hel_kill=0
        dis_sub2_ship
        dis_sub1_ship
        dis_hel1_sub1
        dis_hel1_sub2
        dis_hel2_sub1
        dis_hel2_sub2
        ship_des=0
        sub1_des=0
        sub2_des=0
        sub1_sub2_des=0
        sub1_ship_des=0
        sub2_ship_des=0
        no_one_des=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x, y, x1, y1, x2, y2, x3, y3, x4, y4, x5, y5, d1, d2, d3, d4, d5, d6)
    app.CallingApp=mainapp;
    for j=1:1:20
        ship_x=x;
        ship_y=y;
        MPA_x=x1;
        MPA_y=y1;
        Helicopter_x=x2;
        Helicopter_y=y2;
        Helicopter_x_2=x3;
        Helicopter_y_2=y3;
        sub1_x=x4;
        sub1_y=y4;
        sub2_x=x5;
        sub2_y=y5;
            app.dis_sub1_ship=0;
            app.dis_sub2_ship=0;
            app.dis_hel1_sub1=0;
            app.dis_hel1_sub2=0;
            app.dis_hel2_sub1=0;
            app.dis_hel2_sub2=0;
            app.m=0;
            app.n=0;
            app.o=0;
            app.troops_lost_by_ship=0;
            app.troops_lost_by_sub1=0;
            app.troops_lost_by_sub2=0;
            
            for i=0:1:30
                app.dis_sub1_ship=sqrt((ship_x-sub1_x).^2+(ship_y-sub1_y).^2);
                app.dis_sub2_ship=sqrt((ship_x-sub2_x).^2+(ship_y-sub2_y).^2);
                app.dis_hel1_sub1=sqrt((Helicopter_x-sub1_x).^2+(Helicopter_y-sub1_y).^2);
                app.dis_hel1_sub2=sqrt((Helicopter_x-sub2_x).^2+(Helicopter_y-sub2_y).^2);
                app.dis_hel2_sub1=sqrt((Helicopter_x_2-sub1_x).^2+(Helicopter_y_2-sub1_y).^2);
                app.dis_hel2_sub2=sqrt((Helicopter_x_2-sub2_x).^2+(Helicopter_y_2-sub2_y).^2);
                ship_detection=rand(1,1);
                ship_hit=rand(1,1);
                sub_detection=rand(1,1);
                sub_hit=rand(1,1);
                hel_detection=rand(1,1);
                hel_hit=rand(1,1);
               
                %enemy not detected
                if app.dis_sub2_ship<0.25 && app.m==0 && app.n==0
                    if sub_detection<0.8 && ship_detection<0.8
                        while app.m==0 && app.n==0 
                            a=rand(1,1);
                            if a>=0.5 && sub_hit<0.8 %sub
                                    app.m=1;
                                
                            elseif a<0.5 && ship_hit<0.8 %ship hit
                                    app.n=1;
                            end
                        end
                    elseif sub_detection<0.8 && sub_hit<0.8
                        app.m=1;
                    elseif ship_detection<0.8 && ship_hit<0.8
                            app.n=1;
                            
                    end
                end
    
                if app.dis_hel2_sub2<0.125 && app.n==0 && app.m==0
                    if hel_hit<0.8 && hel_detection<0.8
                            app.n=1;
                    end
                end
                if app.dis_hel2_sub1<0.125 && app.o==0 && app.m==0
                    if hel_hit<0.8 && hel_detection<0.8
                            app.o=1;
                    end
                end
                if app.dis_hel1_sub2<0.125 && app.n==0 && app.m==0
                    if hel_hit<0.8 && hel_detection<0.8
                            app.n=1;
                    end
                end
                if app.dis_hel1_sub1<0.125 && app.o==0 && app.m==0
                    if hel_hit<0.8 && hel_detection<0.8
                            app.o=1;
                    end
                end
                if app.dis_sub1_ship<0.25 && app.m==0 && app.o==0
                    if sub_detection<0.8 && ship_detection<0.8
                        while app.m==0 && app.o==0 
                            b=rand(1,1);
                            if b>=0.5 && sub_hit<0.8 &&app.m==0  %sub hit
                                    app.m=1;
                            elseif b<0.5 && ship_hit<0.8 %ship hit
                                    app.o=1;
                            end
                        end
                    elseif sub_detection<0.8 && sub_hit<0.8
                            app.m=1;
                    elseif ship_detection<0.8 && ship_hit<0.8
                        app.o=1;
                    end
                end
                     Helicopter_x=Helicopter_x+d1;
                    Helicopter_y=Helicopter_y+d2;
                    Helicopter_x_2=Helicopter_x_2+d1;
                    Helicopter_y_2=Helicopter_y_2+d2;
                    MPA_x=MPA_x+d1;
                    MPA_y=MPA_y+d2;
                    ship_x=ship_x+d1;
                    ship_y=ship_y+d2;
                    sub2_x=sub2_x+d5;
                    sub2_y=sub2_y+d6;
                    sub1_x=sub1_x+d3;
                    sub1_y=sub1_y+d4;
                    
    %                if i==0
    %                    
    %                end
                
                
            end
            if app.m==0 && app.n==0 && app.o==0
                app.no_one_des=app.no_one_des+1;
            elseif app.n==1 && app.o==1 && app.m==0
                app.sub1_sub2_des=app.sub1_sub2_des+1;
            elseif app.m==1 && app.o==1 && app.n==0
                app.sub1_ship_des=app.sub1_ship_des+1;
            elseif app.m==1 && app.n==1 && app.o==0
                app.sub2_ship_des=app.sub2_ship_des+1;
            elseif app.n==1 && app.o==0 && app.m==0
                app.sub2_des=app.sub2_des+1;
            elseif app.n==0 && app.o==1 && app.m==0
                app.sub1_des=app.sub1_des+1;
            elseif app.n==0 && app.o==0 && app.m==1
                app.ship_des=app.ship_des+1;
            end
    end
    disp(app.no_one_des)
    disp(app.sub1_des)
    disp(app.sub2_des)
    disp(app.ship_des)
    disp(app.sub1_sub2_des)
    disp(app.sub1_ship_des)
    disp(app.sub2_ship_des)
    Y=linspace(0,1);
    X = categorical({'no one','Sub1','Sub2','Ship','sub1 & ship','sub2 & ship','sub1 & sub2'});
    X = reordercats(X,{'no one','Sub1','Sub2','Ship','sub1 & ship','sub2 & ship','sub1 & sub2'});
    Y = [app.no_one_des/20 app.sub1_des/20 app.sub2_des/20 app.ship_des/20 app.sub1_ship_des/20 app.sub2_ship_des/20 app.sub1_sub2_des/20];
    bar(app.UIAxes,X,Y)


        end

        % Close request function: GraphUIFigure
        function GraphUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphUIFigure = uifigure('Visible', 'off');
            app.GraphUIFigure.Name = 'Graph';
            app.GraphUIFigure.Icon = 'img.jpeg';
            app.GraphUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraphUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.GraphUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Vessel')
            ylabel(app.UIAxes, 'Destroy Prob')
            zlabel(app.UIAxes, 'Z')
            app.DoneButton = uibutton(app.GraphUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.GraphUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphUIFigure)
        end
    end
end