classdef map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapUIFigure                matlab.ui.Figure
        StatusEditField_3          matlab.ui.control.EditField
        StatusEditField_6Label     matlab.ui.control.Label
        StatusEditField_2          matlab.ui.control.EditField
        StatusEditField_5Label     matlab.ui.control.Label
        StatusEditField            matlab.ui.control.EditField
        StatusEditField_4Label     matlab.ui.control.Label
        TroopsDesEditField_2       matlab.ui.control.NumericEditField
        TroopsDesEditField_2Label  matlab.ui.control.Label
        TroopsDesEditField         matlab.ui.control.NumericEditField
        TroopsDesEditFieldLabel    matlab.ui.control.Label
        xyz                        matlab.ui.control.NumericEditField
        TroopsDesLabel             matlab.ui.control.Label
        NextButton                 matlab.ui.control.Button
        ResultsLabel               matlab.ui.control.Label
        ShipLabel                  matlab.ui.control.Label
        Submarine1Label            matlab.ui.control.Label
        Submarine2Label            matlab.ui.control.Label
        REDTASKFORCELabel          matlab.ui.control.Label
        BLUETASKFORCELabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
         CallingApp   % Main app object 
         DialogApp
         troops_lost_by_ship
        troops_lost_by_sub1
        troops_lost_by_sub2
        m
        n
        o
    end
    
    properties (Access = public)
        ship_kill=0 % Description
        sub_kill=0
        hel_kill=0
        dis_sub2_ship
        dis_sub1_ship
        dis_hel1_sub1
        dis_hel1_sub2
        dis_hel2_sub1
        dis_hel2_sub2
        ship_x22,ship_y22, MPA_x22,MPA_y22,Helicopter_x22,Helicopter_y22,Helicopter_x_222,Helicopter_y_222,sub1_x22,sub1_y22,sub2_x22,sub2_y22
        d1,d2,d3,d4,d5,d6
    end


    methods (Access = public)
        
        function updateplot1(app,dis)
            app.ship_kill=exp(-dis/0.25);
        end
        function updateplot2(app,dis)
            app.sub_kill=exp(-dis/0.25);
        end
        function updateplot3(app,dis)
            app.hel_kill=exp(-dis/0.25);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp2, ship_n, MPA_n, hel1_n, hel2_n, sub1_n, sub2_n, troops)
        app.CallingApp = mainapp2;
        fg=figure();
        fg.WindowState='maximized';
        lat_upp_lim = 25;
        lon_upp_lim = 121;
        lat_low_limit = 23;
        lon_low_limit = 117;
        geolimits([lat_low_limit lat_upp_lim],[lon_low_limit lon_upp_lim]);
        geobasemap("grayland");
        hold on


        %ship coordinates
        [x,y]=ginput(1);
        ship_x=x;
        app.ship_x22=x;
        ship_y=y;
        app.ship_y22=y;
        geoplot(ship_x,ship_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
        drawcircle("Center",[ship_x,ship_y],"Radius",.125,"Color",[0 0 1 ]);
        text(ship_x-0.15,ship_y+0.15,ship_n);
        drawrectangle("Position",[ship_x-0.1,ship_y-0.025,.2,.05])
        hold on 
        
        %MPAs coordinates
        MPA_x=x;
        app.MPA_x22=x;
        MPA_y=y;
        app.MPA_y22=y;
        geoplot(MPA_x,MPA_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
        text(MPA_x-0.1,MPA_y+0.1,MPA_n);
        drawcircle("Center",[MPA_x,MPA_y],"Radius",.25,"Color",[0 0 1 ]);
        hold on
        
        
        %helicopters coordinates
        %hel1
        %[x2,y2]=ginput(1);
        Helicopter_x=x+0.25;
        app.Helicopter_x22=Helicopter_x;
        Helicopter_y=y+0.25;
        app.Helicopter_y22=Helicopter_y;
        geoplot(Helicopter_x,Helicopter_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
        drawcircle("Center",[Helicopter_x,Helicopter_y],"Radius",.125,"Color",[0 0 1 ]);
        text(Helicopter_x-0.10,Helicopter_y+0.10,hel1_n);
        geoplot([ship_x Helicopter_x],[ship_y Helicopter_y],'c-*');
        hold on
        
        %hel2
        %[x3,y3]=ginput(1);
        Helicopter_x_2=x-0.25;
        app.Helicopter_x_222=Helicopter_x_2;
        Helicopter_y_2=y-0.25;
        app.Helicopter_y_222=Helicopter_y_2;
        geoplot(Helicopter_x_2,Helicopter_y_2,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
        drawcircle("Center",[Helicopter_x_2,Helicopter_y_2],"Radius",.125,"Color",[0 0 1 ]);
        text(Helicopter_x_2-0.10,Helicopter_y_2+0.10,hel2_n);
        geoplot([ship_x Helicopter_x_2],[ship_y Helicopter_y_2],'c-*');
        hold on
        
        %sub1
        [x2,y2]=ginput(1);
        sub1_x=x2;
        app.sub1_x22=x2;
        sub1_y=y2;
        app.sub1_y22=y2;
        geoplot(sub1_x,sub1_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
        drawcircle("Center",[sub1_x,sub1_y],"Radius",.25,"Color",[1 0 0 ]);
        text(sub1_x-0.10,sub1_y+0.10,sub1_n);
        drawrectangle("Position",[sub1_x-0.1,sub1_y-0.025,.2,0.05],"FaceAlpha",0.9);
        hold on
        
        %sub2
        [x3,y3]=ginput(1);
        sub2_x=x3;
        app.sub2_x22=x3;
        sub2_y=y3;
        app.sub2_y22=y3;
        geoplot(sub2_x,sub2_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
        drawcircle("Center",[sub2_x,sub2_y],"Radius",.25,"Color",[1 0 0 ]);
        text(sub2_x-0.10,sub2_y+0.10,sub2_n);
        drawrectangle("Position",[sub2_x-0.1,sub2_y-0.025,.2,0.05],"FaceAlpha",0.9);
        hold on
        
        
        %path of ship
        [x4,y4]=ginput(1);
            
        app.d1=(x4-x)/30;
        app.d2=(y4-y)/30;
        geoplot([x4 x],[y4 y],'c-*');
        hold on
        
        %path of sub1
        [x5,y5]=ginput(1);
            
        app.d3=(x5-x2)/30;
        app.d4=(y5-y2)/30;

        geoplot([x5 x2],[y5 y2],'c-*');
        hold on
        
        %path of sub2
        [x6,y6]=ginput(1);
            
        app.d5=(x6-x3)/30;
        app.d6=(y6-y3)/30;

        geoplot([x6 x3],[y6 y3],'c-*');
        hold on

        app.m=0;
        app.n=0;
        app.o=0;
        app.troops_lost_by_ship=0;
        app.troops_lost_by_sub1=0;
        app.troops_lost_by_sub2=0;
        for i=0:1:30
         
           %ship at each point
            if app.troops_lost_by_ship==0
            geoplot(ship_x,ship_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
            drawcircle("Center",[ship_x,ship_y],"Radius",.125,"Color",[0 0 1 ]);
            text(ship_x-0.15,ship_y+0.15,ship_n);
            drawrectangle("Position",[ship_x-0.1,ship_y-0.025,.2,.05])
            hold on

            %ship path
            geoplot([ship_x x4],[ship_y y4],'c-*');
            hold on
    
            %MPAs at each point
            geoplot(MPA_x,MPA_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
            text(MPA_x-0.1,MPA_y+0.1,MPA_n);
            drawcircle("Center",[MPA_x,MPA_y],"Radius",.25,"Color",[0 0 1 ]);
            hold on
    
            %Helicopter 1 at each point
            geoplot(Helicopter_x,Helicopter_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
            drawcircle("Center",[Helicopter_x,Helicopter_y],"Radius",.125,"Color",[0 0 1 ]);
            text(Helicopter_x-0.10,Helicopter_y+0.10,hel1_n);
            geoplot([ship_x Helicopter_x],[ship_y Helicopter_y],'c-*');
            hold on
    
            %Helicopter 2 at each point
            geoplot(Helicopter_x_2,Helicopter_y_2,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
            drawcircle("Center",[Helicopter_x_2,Helicopter_y_2],"Radius",.125,"Color",[0 0 1 ]);
            text(Helicopter_x_2-0.10,Helicopter_y_2+0.10,hel2_n);
            geoplot([ship_x Helicopter_x_2],[ship_y Helicopter_y_2],'c-*');
            hold on

            end
    
            if app.troops_lost_by_sub1==0
            %submarine 1 on its way
            geoplot(sub1_x,sub1_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
            drawcircle("Center",[sub1_x,sub1_y],"Radius",.25,"Color",[1 0 0 ]);
            text(sub1_x-0.10,sub1_y+0.10,sub1_n);
            drawrectangle("Position",[sub1_x-0.1,sub1_y-0.025,.2,0.05],"FaceAlpha",0.9);
            hold on

            %sub 1 path
            geoplot([sub1_x x5],[sub1_y y5],'c-*');
            hold on

            end
            
            if app.troops_lost_by_sub2==0
            %submarine 2 on its way
            geoplot(sub2_x,sub2_y,'b^','MarkerFaceColor','blue','MarkerEdgeColor','black');
            drawcircle("Center",[sub2_x,sub2_y],"Radius",.25,"Color",[1 0 0 ]);
            text(sub2_x-0.10,sub2_y+0.10,sub2_n);
            drawrectangle("Position",[sub2_x-0.1,sub2_y-0.025,.2,0.05],"FaceAlpha",0.9);
            hold on

            %sub 2 path
            geoplot([sub2_x x6],[sub2_y y6],'c-*');
            hold on

            end


            ship_cap=troops;
            sub1_cap=250;
            sub2_cap=200;
            app.dis_sub1_ship=sqrt((ship_x-sub1_x).^2+(ship_y-sub1_y).^2);
            app.dis_sub2_ship=sqrt((ship_x-sub2_x).^2+(ship_y-sub2_y).^2);
            app.dis_hel1_sub1=sqrt((Helicopter_x-sub1_x).^2+(Helicopter_y-sub1_y).^2);
            app.dis_hel1_sub2=sqrt((Helicopter_x-sub2_x).^2+(Helicopter_y-sub2_y).^2);
            app.dis_hel2_sub1=sqrt((Helicopter_x_2-sub1_x).^2+(Helicopter_y_2-sub1_y).^2);
            app.dis_hel2_sub2=sqrt((Helicopter_x_2-sub2_x).^2+(Helicopter_y_2-sub2_y).^2);
            ship_detection=rand(1,1);
            ship_hit=rand(1,1);
            sub_detection=rand(1,1);
            sub_hit=rand(1,1);
            hel_detection=rand(1,1);
            hel_hit=rand(1,1);


            %enemy not detected
            if app.dis_sub2_ship<0.25 && app.m==0 && app.n==0
                if sub_detection<0.8 && ship_detection<0.8
                    p=1;
                    while app.m==0 && app.n==0 && p<4
                        a=rand(1,1);
                        if a>=0.5 && sub_hit<0.8 %sub hit
                            app.DialogApp=DialogBoxSubmarine(app,app.dis_sub2_ship);
                            pause(1)
                            if app.sub_kill>0
                                app.troops_lost_by_ship=ship_cap*app.sub_kill;
                                app.m=1;
                            end
                        elseif a<0.5 && ship_hit<0.8 %ship hit
                           app.DialogApp=DialogBoxShip(app,app.dis_sub2_ship);
                           pause(1)
                             if app.ship_kill>0
                                app.troops_lost_by_sub2=sub2_cap*app.ship_kill;
                                app.n=1;
                             end
                        end
                        p=p+1;
                    end
                elseif sub_detection<0.8 && sub_hit<0.8
                    app.DialogApp=DialogBoxSubmarine(app,app.dis_sub2_ship);
                    pause(1)
                    if app.sub_kill>0
                        app.troops_lost_by_ship=ship_cap*app.sub_kill;
                        app.m=1;
                    end
                elseif ship_detection<0.8 && ship_hit<0.8
                     app.DialogApp=DialogBoxShip(app,app.dis_sub2_ship);
                     pause(1)
                     if(app.ship_kill>0)
                        app.troops_lost_by_sub2=sub2_cap*app.ship_kill;
                        app.n=1;
                     end
                        
                end
            end

            if app.dis_hel2_sub2<0.125 && app.n==0 && app.m==0
                if hel_hit<0.8 && hel_detection<0.8
                    app.DialogApp=DialogBoxHelicopter(app,app.dis_hel2_sub2);
                    pause(1)
                    if app.hel_kill>0
                        app.troops_lost_by_sub2=app.hel_kill*sub2_cap;
                        app.n=1;
                    end
                end
            end
            if app.dis_hel2_sub1<0.125 && app.o==0 && app.m==0
                if hel_hit<0.8 && hel_detection<0.8
                    app.DialogApp=DialogBoxHelicopter(app, app.dis_hel2_sub1);
                    pause(1)
                    if app.hel_kill>0
                        app.troops_lost_by_sub1=app.hel_kill*sub1_cap;
                        app.o=1;
                    end
                end
            end
            if app.dis_hel1_sub2<0.125 && app.n==0 && app.m==0
                if hel_hit<0.8 && hel_detection<0.8
                    app.DialogApp=DialogBoxHelicopter(app, app.dis_hel1_sub2);
                    pause(1)
                    if app.hel_kill>0
                        app.troops_lost_by_sub2=app.hel_kill*sub2_cap;
                        app.n=1;
                    end
                end
            end
            if app.dis_hel1_sub1<0.125 && app.o==0 && app.m==0
                if hel_hit<0.8 && hel_detection<0.8
                    app.DialogApp=DialogBoxHelicopter(app, app.dis_hel1_sub1);
                    pause(1)
                    if app.hel_kill>0
                        app.troops_lost_by_sub1=app.hel_kill*sub1_cap;
                        app.o=1;
                    end
                end
            end
            if app.dis_sub1_ship<0.25 && app.m==0 && app.o==0
                if sub_detection<0.8 && ship_detection<0.8
                    q=1;
                    while app.m==0 && app.o==0 && q<4
                        b=rand(1,1);
                        if b>=0.5 && sub_hit<0.8 &&app.m==0  %sub hit
                            app.DialogApp=DialogBoxSubmarine(app, app.dis_sub1_ship);
                            pause(1)
                            if app.sub_kill>0
                                app.troops_lost_by_ship=ship_cap*app.sub_kill;
                                app.m=1;
                            end
                        elseif b<0.5 && ship_hit<0.8 %ship hit
                            app.DialogApp=DialogBoxShip(app,app.dis_sub1_ship);
                            pause(1)
                            if app.ship_kill>0
                                app.troops_lost_by_sub1=sub1_cap*app.ship_kill;
                                app.o=1;
                            end
                        end
                        q=q-1;
                    end
                elseif sub_detection<0.8 && sub_hit<0.8
                    app.DialogApp=DialogBoxSubmarine(app, app.dis_sub1_ship);
                    pause(1)
                    if app.sub_kill>0
                        app.troops_lost_by_ship=ship_cap*app.sub_kill;
                        app.m=1;
                    end
                elseif ship_detection<0.8 && ship_hit<0.8
                    app.DialogApp=DialogBoxShip(app, app.dis_sub1_ship);
                    pause(1)
                    if app.ship_kill>0
                    app.troops_lost_by_sub1=sub1_cap*app.ship_kill;
                    app.o=1;
                    end
                end
            end


            if app.m==0
                 Helicopter_x=Helicopter_x+app.d1;
                Helicopter_y=Helicopter_y+app.d2;
                Helicopter_x_2=Helicopter_x_2+app.d1;
                Helicopter_y_2=Helicopter_y_2+app.d2;
                MPA_x=MPA_x+app.d1;
                MPA_y=MPA_y+app.d2;
                ship_x=ship_x+app.d1;
                ship_y=ship_y+app.d2;
    
            end
            if app.n==0
                sub2_x=sub2_x+app.d5;
                sub2_y=sub2_y+app.d6;
            end
            if app.o==0
                sub1_x=sub1_x+app.d3;
                sub1_y=sub1_y+app.d4;
            end
            pause(1)
            cla
            
        end
           
        close
          app.xyz.Value=round(app.troops_lost_by_ship);
          app.TroopsDesEditField.Value=round(app.troops_lost_by_sub1);
          app.TroopsDesEditField_2.Value=round(app.troops_lost_by_sub2);
          if app.m==0
              app.StatusEditField.Value='Survived';
          else
              app.StatusEditField.Value='Destroyed';
          end
          if app.o==0
              app.StatusEditField_2.Value='Survived';
          else
              app.StatusEditField_2.Value='Destroyed';
          end
          if app.n==0
              app.StatusEditField_3.Value='Survived';
          else
              app.StatusEditField_3.Value='Destroyed';
          end


        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.DialogApp=graph(app,app.ship_x22,app.ship_y22,app.MPA_x22,app.MPA_y22,app.Helicopter_x22,app.Helicopter_y22,app.Helicopter_x_222,app.Helicopter_y_222 ,app.sub1_x22,app.sub1_y22,app.sub2_x22,app.sub2_y22,app.d1,app.d2,app.d3,app.d4,app.d5,app.d6);
            delete(app.CallingApp)
            delete(app)
        end

        % Close request function: MapUIFigure
        function MapUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapUIFigure = uifigure('Visible', 'off');
            app.MapUIFigure.Name = 'Map';
            app.MapUIFigure.Icon = 'img.jpeg';
            app.MapUIFigure.CloseRequestFcn = createCallbackFcn(app, @MapUIFigureCloseRequest, true);
            app.BLUETASKFORCELabel = uilabel(app.MapUIFigure);
            app.BLUETASKFORCELabel.Text = 'BLUE TASK FORCE';
            app.REDTASKFORCELabel = uilabel(app.MapUIFigure);
            app.REDTASKFORCELabel.Text = 'RED TASK FORCE';
            app.Submarine2Label = uilabel(app.MapUIFigure);
            app.Submarine2Label.Text = 'Submarine 2';
            app.Submarine1Label = uilabel(app.MapUIFigure);
            app.Submarine1Label.Text = 'Submarine 1';
            app.ShipLabel = uilabel(app.MapUIFigure);
            app.ShipLabel.Text = 'Ship';
            app.ResultsLabel = uilabel(app.MapUIFigure);
            app.ResultsLabel.Text = 'Results';
            app.NextButton = uibutton(app.MapUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.TroopsDesLabel = uilabel(app.MapUIFigure);
            app.TroopsDesLabel.Text = 'Troops Des';
            app.xyz = uieditfield(app.MapUIFigure, 'numeric');
            app.xyz.Editable = 'off';
            app.TroopsDesEditFieldLabel = uilabel(app.MapUIFigure);
            app.TroopsDesEditFieldLabel.Text = 'Troops Des';
            app.TroopsDesEditField = uieditfield(app.MapUIFigure, 'numeric');
            app.TroopsDesEditField.Editable = 'off';
            app.TroopsDesEditField_2Label = uilabel(app.MapUIFigure);
            app.TroopsDesEditField_2Label.Text = 'Troops Des';
            app.TroopsDesEditField_2 = uieditfield(app.MapUIFigure, 'numeric');
            app.TroopsDesEditField_2.Editable = 'off';
            app.StatusEditField_4Label = uilabel(app.MapUIFigure);
            app.StatusEditField_4Label.Text = 'Status';
            app.StatusEditField = uieditfield(app.MapUIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField_5Label = uilabel(app.MapUIFigure);
            app.StatusEditField_5Label.Text = 'Status';
            app.StatusEditField_2 = uieditfield(app.MapUIFigure, 'text');
            app.StatusEditField_2.Editable = 'off';
            app.StatusEditField_6Label = uilabel(app.MapUIFigure);
            app.StatusEditField_6Label.Text = 'Status';
            app.StatusEditField_3 = uieditfield(app.MapUIFigure, 'text');
            app.StatusEditField_3.Editable = 'off';
            % Show the figure after all components are created
            app.MapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = map(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapUIFigure)
        end
    end
end