classdef select < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectUIFigure           matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        REDTASKFORCELabel        matlab.ui.control.Label
        BLUETASKFORCELabel       matlab.ui.control.Label
        Submarine2DropDown       matlab.ui.control.DropDown
        Submarine2DropDownLabel  matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        Submarine1DropDown       matlab.ui.control.DropDown
        Submarine1DropDownLabel  matlab.ui.control.Label
        Helicopter2DropDown      matlab.ui.control.DropDown
        Helicopter2Label         matlab.ui.control.Label
        Helicopter1DropDown      matlab.ui.control.DropDown
        Helicopter1Label         matlab.ui.control.Label
        MPAsDropDown             matlab.ui.control.DropDown
        MPAsLabel                matlab.ui.control.Label
        ShipDropDown             matlab.ui.control.DropDown
        ShipLabel                matlab.ui.control.Label
        SelectTaskForceLabel     matlab.ui.control.Label
    end

    
    properties (Access=private)
        % Description
        DialogApp
        CallingApp
        ship_n
        MPA_n
        hel1_n
        hel2_n
        sub1_n
        sub2_n
        value1
        troops
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
                app.CallingApp=mainapp;
                
                
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
%             app.CallingApp=mainapp;
            app.DialogApp = map(app,app.ship_n,app.MPA_n,app.hel1_n,app.hel2_n,app.sub1_n,app.sub2_n,app.troops);
%             app.STARTButton.Enable = 'off';
            delete(app)
        end

        % Value changed function: ShipDropDown
        function ShipDropDownValueChanged(app, event)
             app.ship_n=app.ShipDropDown.Value;
             if app.ship_n=="Yuliang"
                app.TextArea.Value="LSM Troops: 250";
                app.troops=250;
            elseif app.ship_n=="Yukan"
                app.TextArea.Value="LST Troops: 200";
                app.troops=200;
            elseif app.ship_n=="INS Shardul"
                app.TextArea.Value="LST Troops: 500";
                app.troops=500;
             elseif app.ship_n=="INS Kesari"
                app.TextArea.Value="LST Troops: 500";
                app.troops=500;
            elseif app.ship_n=="Yuting"
                app.TextArea.Value="LST Troops: 250";
                app.troops=250;
            elseif app.ship_n=="Yudeng"
                app.TextArea.Value="LSM Troops: 180";
                app.troops=180;
            elseif app.ship_n=="Yuhai"
                app.TextArea.Value="LSM Troops: 250";
                app.troops=250;
            elseif app.ship_n=="Yuting II"
                app.TextArea.Value="LST Troops: 250";
                app.troops=250;
            elseif app.ship_n=="Yubei"
                app.TextArea.Value="LCU Troops: 200";
                app.troops=200;
            elseif app.ship_n=="Yunshu"
                app.TextArea.Value="LSM Troops: 500";
                app.troops=500;
            elseif app.ship_n=="Yuzhao"
                app.TextArea.Value="LPD Troops: 600";
                app.troops=600;
            elseif app.ship_n=="Type 081"
                app.TextArea.Value="LHD Troops: 900";
                app.troops=900;
            
            end
        end

        % Close request function: SelectUIFigure
        function SelectUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: Helicopter1DropDown
        function Helicopter1DropDownValueChanged(app, event)
             app.hel1_n = app.Helicopter1DropDown.Value;
        end

        % Value changed function: MPAsDropDown
        function MPAsDropDownValueChanged(app, event)
             app.MPA_n = app.MPAsDropDown.Value;
        end

        % Value changed function: Helicopter2DropDown
        function Helicopter2DropDownValueChanged(app, event)
             app.hel2_n = app.Helicopter2DropDown.Value;
        end

        % Value changed function: Submarine1DropDown
        function Submarine1DropDownValueChanged(app, event)
            app.sub1_n = app.Submarine1DropDown.Value;
        end

        % Value changed function: Submarine2DropDown
        function Submarine2DropDownValueChanged(app, event)
             app.sub2_n = app.Submarine2DropDown.Value;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            if app.ShipDropDown.Value=="Yuliang"
                app.TextArea.Value='LST 250';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectUIFigure = uifigure('Visible', 'off');
            app.SelectUIFigure.Name = 'Select';
            app.SelectUIFigure.Icon = 'img.jpeg';
            app.SelectUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectUIFigureCloseRequest, true);
            app.SelectTaskForceLabel = uilabel(app.SelectUIFigure);
            app.SelectTaskForceLabel.Text = 'Select Task Force';
            app.ShipLabel = uilabel(app.SelectUIFigure);
            app.ShipLabel.Text = 'Ship  ';
            app.ShipDropDown = uidropdown(app.SelectUIFigure);
            app.ShipDropDown.Items = {'Select', 'INS Shardul', 'Yuliang', 'INS Kesari', 'Yukan', 'Yuting', 'Yudeng', 'Yuhai', 'Yuting', 'Yubei', 'Yunshu', 'Yuzhao', 'Type 081'};
            app.ShipDropDown.ValueChangedFcn = createCallbackFcn(app, @ShipDropDownValueChanged, true);
            app.ShipDropDown.Value = 'Select';
            app.MPAsLabel = uilabel(app.SelectUIFigure);
            app.MPAsLabel.Text = 'MPAs   ';
            app.MPAsDropDown = uidropdown(app.SelectUIFigure);
            app.MPAsDropDown.Items = {'Select', 'Z-5', 'Z-8', 'Ka-28', 'Z-9C', 'Z-18F'};
            app.MPAsDropDown.ValueChangedFcn = createCallbackFcn(app, @MPAsDropDownValueChanged, true);
            app.MPAsDropDown.Value = 'Select';
            app.Helicopter1Label = uilabel(app.SelectUIFigure);
            app.Helicopter1Label.Text = 'Helicopter 1  ';
            app.Helicopter1DropDown = uidropdown(app.SelectUIFigure);
            app.Helicopter1DropDown.Items = {'Select', 'MI-25/35', 'Apache AH-64E', 'H-5', 'SH-5', 'Y-8X'};
            app.Helicopter1DropDown.ValueChangedFcn = createCallbackFcn(app, @Helicopter1DropDownValueChanged, true);
            app.Helicopter1DropDown.Value = 'Select';
            app.Helicopter2Label = uilabel(app.SelectUIFigure);
            app.Helicopter2Label.Text = 'Helicopter 2  ';
            app.Helicopter2DropDown = uidropdown(app.SelectUIFigure);
            app.Helicopter2DropDown.Items = {'Select', 'MI-25/35', 'Apache AH-64E', 'H-5', 'SH-5', 'Y-8X'};
            app.Helicopter2DropDown.ValueChangedFcn = createCallbackFcn(app, @Helicopter2DropDownValueChanged, true);
            app.Helicopter2DropDown.Value = 'Select';
            app.Submarine1DropDownLabel = uilabel(app.SelectUIFigure);
            app.Submarine1DropDownLabel.Text = 'Submarine 1';
            app.Submarine1DropDown = uidropdown(app.SelectUIFigure);
            app.Submarine1DropDown.Items = {'Select', 'Kalvari Class', 'Sindhugosh Class', 'Los Angeles Class', 'Virginia Class'};
            app.Submarine1DropDown.ValueChangedFcn = createCallbackFcn(app, @Submarine1DropDownValueChanged, true);
            app.Submarine1DropDown.Value = 'Select';
            app.STARTButton = uibutton(app.SelectUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Submarine2DropDownLabel = uilabel(app.SelectUIFigure);
            app.Submarine2DropDownLabel.Text = 'Submarine 2';
            app.Submarine2DropDown = uidropdown(app.SelectUIFigure);
            app.Submarine2DropDown.Items = {'Select', 'Kalvari Class', 'Sindhugosh Class', 'Los Angeles Class', 'Virginia Class'};
            app.Submarine2DropDown.ValueChangedFcn = createCallbackFcn(app, @Submarine2DropDownValueChanged, true);
            app.Submarine2DropDown.Value = 'Select';
            app.BLUETASKFORCELabel = uilabel(app.SelectUIFigure);
            app.BLUETASKFORCELabel.Text = 'BLUE TASK FORCE';
            app.REDTASKFORCELabel = uilabel(app.SelectUIFigure);
            app.REDTASKFORCELabel.Text = 'RED TASK FORCE';
            app.TextArea = uitextarea(app.SelectUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SelectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectUIFigure)
        end
    end
end